classdef Springifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpringifierUIFigure            matlab.ui.Figure
        SpringifierTwistyEditionLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SpringInvestigatorTab          matlab.ui.container.Tab
        MaterialPropertiesPanel        matlab.ui.container.Panel
        ELabel                         matlab.ui.control.Label
        Youngs_Modulus                 matlab.ui.control.NumericEditField
        GLabel                         matlab.ui.control.Label
        Shear_Modulus                  matlab.ui.control.NumericEditField
        nuLabel                        matlab.ui.control.Label
        Poissons_Ratio                 matlab.ui.control.NumericEditField
        EngineeringDataPanel           matlab.ui.container.Panel
        ActiveHeightH0Label            matlab.ui.control.Label
        Active_Height_H0               matlab.ui.control.NumericEditField
        ActiveCoilsLabel               matlab.ui.control.Label
        Active_Coils                   matlab.ui.control.NumericEditField
        MeanDiameterLabel              matlab.ui.control.Label
        Mean_Diameter                  matlab.ui.control.NumericEditField
        SolidHeightLabel               matlab.ui.control.Label
        Solid_Height                   matlab.ui.control.NumericEditField
        StiffnessLabel                 matlab.ui.control.Label
        Stiffness                      matlab.ui.control.NumericEditField
        TwistAngleLabel                matlab.ui.control.Label
        Twist_Angle                    matlab.ui.control.NumericEditField
        ForceLabel                     matlab.ui.control.Label
        Force                          matlab.ui.control.NumericEditField
        DeflectionLabel                matlab.ui.control.Label
        Deflection                     matlab.ui.control.NumericEditField
        MaxDiameterLabel               matlab.ui.control.Label
        Max_Diameter                   matlab.ui.control.NumericEditField
        LoadedCoilsLabel               matlab.ui.control.Label
        Loaded_Coils                   matlab.ui.control.NumericEditField
        ActiveHeightH1Label            matlab.ui.control.Label
        Active_Height_H1               matlab.ui.control.NumericEditField
        PitchLabel                     matlab.ui.control.Label
        Pitch                          matlab.ui.control.NumericEditField
        PitchAngleLabel                matlab.ui.control.Label
        Number                         matlab.ui.control.NumericEditField
        ManufacturingDataPanel         matlab.ui.container.Panel
        FreeLengthLabel                matlab.ui.control.Label
        Free_Length                    matlab.ui.control.NumericEditField
        NumberCoilsLabel               matlab.ui.control.Label
        Number_Coils                   matlab.ui.control.NumericEditField
        WireDiameterLabel              matlab.ui.control.Label
        Wire_Diameter                  matlab.ui.control.NumericEditField
        EndConditionDropDownLabel      matlab.ui.control.Label
        EndCondition                   matlab.ui.control.DropDown
        InnerDiameterLabel             matlab.ui.control.Label
        Inner_Diameter                 matlab.ui.control.NumericEditField
        OuterDiameterLabel             matlab.ui.control.Label
        Outer_Diameter                 matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        XAxisButtonGroup               matlab.ui.container.ButtonGroup
        MeanDiameterButton_X           matlab.ui.control.ToggleButton
        WireDiameterButton_X           matlab.ui.control.ToggleButton
        NumberCoilsButton_X            matlab.ui.control.ToggleButton
        FreeLengthButton_X             matlab.ui.control.ToggleButton
        DeflectionButton_X             matlab.ui.control.ToggleButton
        PitchButton_X                  matlab.ui.control.ToggleButton
        YAxisButtonGroup               matlab.ui.container.ButtonGroup
        TwistAngleButton_Y             matlab.ui.control.ToggleButton
        MaximumDiameterButton_Y        matlab.ui.control.ToggleButton
        ActiveCoilsButton_Y            matlab.ui.control.ToggleButton
        SolidHeightButton_Y            matlab.ui.control.ToggleButton
        ForceButton_Y                  matlab.ui.control.ToggleButton
        StiffnessButton_Y              matlab.ui.control.ToggleButton
        LegendButtonGroup              matlab.ui.container.ButtonGroup
        NumberCoilsButton_L            matlab.ui.control.ToggleButton
        WireDiameterButton_L           matlab.ui.control.ToggleButton
        MeanDiameterButton_L           matlab.ui.control.ToggleButton
        NoneButton_L                   matlab.ui.control.ToggleButton
        Image                          matlab.ui.control.Image
        SpringDesignerTab              matlab.ui.container.Tab
        ComingSoontoComputersNearYouLabel  matlab.ui.control.Label
        OptimizerTabLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Update_Free_Length(app, event)
            value = app.Free_Length.Value;  
        end

        % Selection changed function: XAxisButtonGroup
        function XAxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.XAxisButtonGroup.SelectedObject;
            switch selectedButton
                case  app.MeanDiameterButton_X
                    app.TwistAngleButton_Y.Enable = true;
                    app.MaximumDiameterButton_Y.Enable = false;
                    app.ActiveCoilsButton_Y.Enable = false;
                case  app.WireDiameterButton_X
                    
                case  app.NumberCoilsButton_X
                    
                case  app.FreeLengthButton_X
                    
                case  app.DeflectionButton_X
                    
                case  app.PitchButton_X
                    
                   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpringifierUIFigure = uifigure('Visible', 'off');
            app.SpringifierUIFigure.Name = 'Springifier';
            app.SpringifierTwistyEditionLabel = uilabel(app.SpringifierUIFigure);
            app.SpringifierTwistyEditionLabel.Text = 'Springifier - Twisty Edition';
            app.TabGroup = uitabgroup(app.SpringifierUIFigure);
            app.SpringInvestigatorTab = uitab(app.TabGroup);
            app.SpringInvestigatorTab.Title = 'Spring Investigator';
            app.MaterialPropertiesPanel = uipanel(app.SpringInvestigatorTab);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.ELabel = uilabel(app.MaterialPropertiesPanel);
            app.ELabel.Text = 'E';
            app.Youngs_Modulus = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Youngs_Modulus.Limits = [0 Inf];
            app.Youngs_Modulus.Tooltip = {'Young''s Modulus -'; ' GPa'};
            app.Youngs_Modulus.Value = 210;
            app.GLabel = uilabel(app.MaterialPropertiesPanel);
            app.GLabel.Text = 'G';
            app.Shear_Modulus = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Shear_Modulus.Limits = [0 Inf];
            app.Shear_Modulus.Tooltip = {'Shear Modulus - GPa'};
            app.Shear_Modulus.Value = 79;
            app.nuLabel = uilabel(app.MaterialPropertiesPanel);
            app.nuLabel.Text = 'nu';
            app.Poissons_Ratio = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Poissons_Ratio.Limits = [0 Inf];
            app.Poissons_Ratio.Editable = 'off';
            app.Poissons_Ratio.Tooltip = {'E/G = 2(1+nu)'};
            app.Poissons_Ratio.Value = 0.3;
            app.EngineeringDataPanel = uipanel(app.SpringInvestigatorTab);
            app.EngineeringDataPanel.Title = 'Engineering Data';
            app.ActiveHeightH0Label = uilabel(app.EngineeringDataPanel);
            app.ActiveHeightH0Label.Text = 'Active Height - H0';
            app.Active_Height_H0 = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Active_Height_H0.Limits = [0 Inf];
            app.Active_Height_H0.Tooltip = {'May depend on end conditions'};
            app.ActiveCoilsLabel = uilabel(app.EngineeringDataPanel);
            app.ActiveCoilsLabel.Text = 'Active Coils';
            app.Active_Coils = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Active_Coils.Limits = [0 Inf];
            app.Active_Coils.Editable = 'off';
            app.Active_Coils.Tooltip = {'May depend on end condition'};
            app.MeanDiameterLabel = uilabel(app.EngineeringDataPanel);
            app.MeanDiameterLabel.Text = 'Mean Diameter';
            app.Mean_Diameter = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Mean_Diameter.Limits = [0 Inf];
            app.Mean_Diameter.Tooltip = {'m'};
            app.SolidHeightLabel = uilabel(app.EngineeringDataPanel);
            app.SolidHeightLabel.Text = 'Solid Height';
            app.Solid_Height = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Solid_Height.Limits = [0 Inf];
            app.Solid_Height.Tooltip = {'m'};
            app.StiffnessLabel = uilabel(app.EngineeringDataPanel);
            app.StiffnessLabel.Text = 'Stiffness';
            app.Stiffness = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Stiffness.Limits = [0 Inf];
            app.Stiffness.Editable = 'off';
            app.Stiffness.Tooltip = {'N/m - computed [roperty from geometry and material properties'};
            app.TwistAngleLabel = uilabel(app.EngineeringDataPanel);
            app.TwistAngleLabel.Text = 'Twist Angle';
            app.Twist_Angle = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Twist_Angle.Tooltip = {'Twist angle induced by loading'; ' degrees'};
            app.ForceLabel = uilabel(app.EngineeringDataPanel);
            app.ForceLabel.Text = 'Force';
            app.Force = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Force.Tooltip = {'Newtons'};
            app.DeflectionLabel = uilabel(app.EngineeringDataPanel);
            app.DeflectionLabel.Text = 'Deflection';
            app.Deflection = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Deflection.Tooltip = {'Newtons'};
            app.MaxDiameterLabel = uilabel(app.EngineeringDataPanel);
            app.MaxDiameterLabel.Text = 'Max Diameter';
            app.Max_Diameter = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Max_Diameter.Editable = 'off';
            app.Max_Diameter.Tooltip = {'Maximum diamter under load'};
            app.LoadedCoilsLabel = uilabel(app.EngineeringDataPanel);
            app.LoadedCoilsLabel.Text = 'Loaded Coils';
            app.Loaded_Coils = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Loaded_Coils.Editable = 'off';
            app.Loaded_Coils.Tooltip = {'Number of active coils under load'};
            app.ActiveHeightH1Label = uilabel(app.EngineeringDataPanel);
            app.ActiveHeightH1Label.Text = 'Active Height - H1';
            app.Active_Height_H1 = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Active_Height_H1.Editable = 'off';
            app.Active_Height_H1.Tooltip = {'Number of active coils under load'};
            app.PitchLabel = uilabel(app.EngineeringDataPanel);
            app.PitchLabel.Text = 'Pitch';
            app.Pitch = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Pitch.Tooltip = {'Newtons'};
            app.PitchAngleLabel = uilabel(app.EngineeringDataPanel);
            app.PitchAngleLabel.Text = 'Pitch Angle';
            app.Number = uieditfield(app.EngineeringDataPanel, 'numeric');
            app.Number.Limits = [0 Inf];
            app.Number.Editable = 'off';
            app.ManufacturingDataPanel = uipanel(app.SpringInvestigatorTab);
            app.ManufacturingDataPanel.Title = 'Manufacturing Data';
            app.FreeLengthLabel = uilabel(app.ManufacturingDataPanel);
            app.FreeLengthLabel.Text = 'Free Length';
            app.Free_Length = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Free_Length.Limits = [0 Inf];
            app.NumberCoilsLabel = uilabel(app.ManufacturingDataPanel);
            app.NumberCoilsLabel.Text = 'Number Coils';
            app.Number_Coils = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Number_Coils.Limits = [0 Inf];
            app.WireDiameterLabel = uilabel(app.ManufacturingDataPanel);
            app.WireDiameterLabel.Text = 'Wire Diameter';
            app.Wire_Diameter = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Wire_Diameter.Limits = [0 Inf];
            app.EndConditionDropDownLabel = uilabel(app.ManufacturingDataPanel);
            app.EndConditionDropDownLabel.Text = 'End Condition';
            app.EndCondition = uidropdown(app.ManufacturingDataPanel);
            app.EndCondition.Items = {'Open', 'Closed', 'Closed-Ground'};
            app.EndCondition.Value = 'Open';
            app.InnerDiameterLabel = uilabel(app.ManufacturingDataPanel);
            app.InnerDiameterLabel.Text = 'Inner Diameter';
            app.Inner_Diameter = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Inner_Diameter.Limits = [0 Inf];
            app.OuterDiameterLabel = uilabel(app.ManufacturingDataPanel);
            app.OuterDiameterLabel.Text = 'Outer Diameter';
            app.Outer_Diameter = uieditfield(app.ManufacturingDataPanel, 'numeric');
            app.Outer_Diameter.Limits = [0 Inf];
            app.UIAxes = uiaxes(app.SpringInvestigatorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XAxisButtonGroup = uibuttongroup(app.SpringInvestigatorTab);
            app.XAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XAxisButtonGroupSelectionChanged, true);
            app.XAxisButtonGroup.Tooltip = {'Select menu item'};
            app.XAxisButtonGroup.Title = 'X-Axis';
            app.MeanDiameterButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.MeanDiameterButton_X.Text = 'Mean Diameter';
            app.MeanDiameterButton_X.Value = true;
            app.WireDiameterButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.WireDiameterButton_X.Text = 'Wire Diameter';
            app.NumberCoilsButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.NumberCoilsButton_X.Text = 'Number Coils';
            app.FreeLengthButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.FreeLengthButton_X.Text = 'Free Length';
            app.DeflectionButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.DeflectionButton_X.Text = 'Deflection';
            app.PitchButton_X = uitogglebutton(app.XAxisButtonGroup);
            app.PitchButton_X.Text = 'Pitch';
            app.YAxisButtonGroup = uibuttongroup(app.SpringInvestigatorTab);
            app.YAxisButtonGroup.Tooltip = {'Select menu item'};
            app.YAxisButtonGroup.Title = 'Y-Axis';
            app.TwistAngleButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.TwistAngleButton_Y.Text = 'Twist Angle';
            app.MaximumDiameterButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.MaximumDiameterButton_Y.Text = 'Maximum Diameter';
            app.ActiveCoilsButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.ActiveCoilsButton_Y.Text = 'Active Coils';
            app.SolidHeightButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.SolidHeightButton_Y.Text = 'Solid Height';
            app.ForceButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.ForceButton_Y.Text = 'Force';
            app.StiffnessButton_Y = uitogglebutton(app.YAxisButtonGroup);
            app.StiffnessButton_Y.Text = 'Stiffness';
            app.LegendButtonGroup = uibuttongroup(app.SpringInvestigatorTab);
            app.LegendButtonGroup.Tooltip = {'Select menu item'};
            app.LegendButtonGroup.Title = 'Legend';
            app.NumberCoilsButton_L = uitogglebutton(app.LegendButtonGroup);
            app.NumberCoilsButton_L.Text = 'Number Coils';
            app.WireDiameterButton_L = uitogglebutton(app.LegendButtonGroup);
            app.WireDiameterButton_L.Text = 'Wire Diameter';
            app.MeanDiameterButton_L = uitogglebutton(app.LegendButtonGroup);
            app.MeanDiameterButton_L.Text = 'Mean Diameter';
            app.NoneButton_L = uitogglebutton(app.LegendButtonGroup);
            app.NoneButton_L.Text = 'None';
            app.Image = uiimage(app.SpringInvestigatorTab);
            app.Image.ImageSource = 'spring.png';
            app.SpringDesignerTab = uitab(app.TabGroup);
            app.SpringDesignerTab.Title = 'Spring Designer';
            app.ComingSoontoComputersNearYouLabel = uilabel(app.SpringDesignerTab);
            app.ComingSoontoComputersNearYouLabel.Text = 'Coming Soon to Computers Near You';
            app.OptimizerTabLabel = uilabel(app.SpringDesignerTab);
            app.OptimizerTabLabel.Text = 'Optimizer Tab';
            % Show the figure after all components are created
            app.SpringifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Springifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpringifierUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpringifierUIFigure)
        end
    end
end